classdef Classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        FileMenu      matlab.ui.container.Menu
        SaveMenu      matlab.ui.container.Menu
        LoadMenu      matlab.ui.container.Menu
        TabGroup      matlab.ui.container.TabGroup
        SummaryTab    matlab.ui.container.Tab
        UIAxes        matlab.ui.control.UIAxes
        UITable       matlab.ui.control.Table
        WiFiTab       matlab.ui.container.Tab
        UIAxes_2      matlab.ui.control.UIAxes
        UITable_2     matlab.ui.control.Table
        BluetoothTab  matlab.ui.container.Tab
        UIAxes_3      matlab.ui.control.UIAxes
        UITable_3     matlab.ui.control.Table
        OtherTab      matlab.ui.container.Tab
        UIAxes_4      matlab.ui.control.UIAxes
        UITable_4     matlab.ui.control.Table
        RunButton     matlab.ui.control.Button
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Run startup code to gain access to filepaths and necessary
            % variables
            run Scripting\startup.m
            
            % Set the sample flag so that GNURadio will release the sample
            % file
            sampleFlag = fopen('Runtime\sample.dyn', 'w');
            fclose(sampleFlag);
            
            % Import the sample file to MATLAB
            run Scripting\importSample.m
            
            % Call functions and scripts to calculate signal metadata
            run Scripting\freqBand.m
            classification = radioClassify(centFreq, band);
            modulation = modClassify(centFreq, band);
            
            
            % Save the calculated data to a temprary file
            save('Runtime\data.mat', 'f', 'X1', 'centFreq','classification', 'band', 'modulation')
            
            % Plot to Main graph
            plot(app.UIAxes, f, X1);
            
             % Plot to Main Table
            app.UITable.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
            app.UITable.Data = [centFreq'/(10^6), classification', modulation', band'/(10^6)];
            
            % Initialize Y-axis variables for the remaining plots
            X2 = zeros(size(X1))-100;
            X3 = zeros(size(X1))-100;
            X4 = zeros(size(X1))-100;
            
            app.UITable_2.Data = [];
            app.UITable_3.Data = [];
            app.UITable_4.Data = [];
            
            % Loop through each of the captured signals
            for i = 1:length(classification)
                
                    if classification(i) == "Wi-Fi"
                        % Plot only Wi-Fi signal portions to the Wi-Fi plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X2(index) = X1(index); % Add Wi-Fi portion to initialized Y-axis variable
                        plot(app.UIAxes_2, f, X2);
                        
                        % Plot only Wi-Fi metadata portions to the Wi-Fi
                        % table
                        app.UITable_2.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_2,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_2,'Data',newData);
                        
                        
                    elseif classification(i) == "Bluetooth"
                        % Plot only Bluetooth signal portions to the Bluetooth plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X3(index) = X1(index); % Add Bluetooth portion to initialized Y-axis variable
                        plot(app.UIAxes_3, f, X3);
                        
                        % Plot only Bluetooth metadata portions to the
                        % Bluetooth table
                        app.UITable_3.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_3,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_3,'Data',newData)
                        
                        
                    else
                        % Plot only remaining signal portions to the Other plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X4(index) = X1(index); % Add Other portion to initialized Y-axis variable
                        plot(app.UIAxes_4, f, X4);
                        
                        % Plot only remaining metadata portions to the
                        % Other table
                        app.UITable_4.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_4,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_4,'Data',newData)
                    end
            end
            
            % Set run flag so that the program knows it has been run
            runFlag = fopen('Runtime\ran.dyn', 'w');
            fclose(runFlag);
           
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % Check that the file has been run
            if isfile('Runtime\ran.dyn')
                % Create an folder with the current date and time as the name
                date = datetime('now');
                fileName =  datestr(date, 'yyyy-mm-dd HH-MM-ss');
                mkdir("Archive\" + fileName)
                % Save the runtime files to the new folder
                copyfile('Runtime\sample.mat', "Archive\" + fileName + "\sample.mat")
                copyfile('Runtime\data.mat', "Archive\" + fileName + "\data.mat")
                msgbox('Sample saved.')
            else
                % Error if the program has not run
                errordlg('There is no sample to save.', 'Missing Sample')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete temporary files on program close
            if isfile('Runtime\ran.dyn')
                delete Runtime\ran.dyn
            end
            if isfile('sample.mat')
                delete sample.mat
            end
            delete(app)
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            % Allow user to choose the directory to load data from
            folderName = uigetdir('Archive\');
            if folderName == 0
                return; % Do nothing if no selection
            else
                % Load the required files into the workspace
                load(strcat(folderName, '\data.mat'));
                
                % Plot to Main graph
                plot(app.UIAxes, f, X1);
                
                classification
                
                % Plot to Main table
                app.UITable.ColumnName = {'Center Frequency', 'classificaiton', 'Modulation Type', 'Bandwidth'};
                app.UITable.Data = [centFreq', classification', modulation', band'];
                
                % Initialize Y-axis variables for the remaining plots
                X2 = zeros(size(X1))-100;
                X3 = zeros(size(X1))-100;
                X4 = zeros(size(X1))-100;
                
                plot(app.UIAxes_2, 0, 0);
                plot(app.UIAxes_3, 0, 0);
                plot(app.UIAxes_4, 0, 0);
                app.UITable_2.Data = [];
                app.UITable_3.Data = [];
                app.UITable_4.Data = [];
                
                for i = 1:length(classification)
                
                    if classification(i) == "Wi-Fi"
                        % Plot only Wi-Fi signal portions to the Wi-Fi plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X2(index) = X1(index); % Add Wi-Fi portion to initialized Y-axis variable
                        plot(app.UIAxes_2, f, X2);
                        
                        % Plot only Wi-Fi metadata portions to the Wi-Fi
                        % table
                        app.UITable_2.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_2,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_2,'Data',newData);
                        
                        
                    elseif classification(i) == "Bluetooth"
                        % Plot only Bluetooth signal portions to the Bluetooth plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X3(index) = X1(index); % Add Bluetooth portion to initialized Y-axis variable
                        plot(app.UIAxes_3, f, X3);
                        
                        % Plot only Bluetooth metadata portions to the
                        % Bluetooth table
                        app.UITable_3.ColumnName = {'Center Frequency (MHz)', 'classifificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_3,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_3,'Data',newData)
                        
                        
                    else
                        % Plot only remaining signal portions to the Other plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X4(index) = X1(index); % Add Other portion to initialized Y-axis variable
                        plot(app.UIAxes_4, f, X4);
                        
                        % Plot only remaining metadata portions to the
                        % Other table
                        app.UITable_4.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_4,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_4,'Data',newData)
                    end
                end
                              
                runFlag = fopen('Runtime\ran.dyn', 'w');
                fclose(runFlag);
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            % S shortcut
            if strcmp(key, 's')
                % Check that the file has been run
                if isfile('Runtime\ran.dyn')
                    % Create an folder with the current date and time as the name
                    date = datetime('now');
                    fileName =  datestr(date, 'yyyy-mm-dd HH-MM-ss');
                    mkdir("Archive\" + fileName)
                    % Save the runtime files to the new folder
                    copyfile('Runtime\sample.mat', "Archive\" + fileName + "\sample.mat")
                    copyfile('Runtime\data.mat', "Archive\" + fileName + "\data.mat")
                    msgbox('Sample saved.')
                else
                    % Error if the program has not run
                    errordlg('There is no sample to save.', 'Missing Sample')
                end
              
            % L shortcut
            elseif strcmp(key, 'l')
                % Allow user to choose the directory to load data from
            folderName = uigetdir('Archive\');
            if folderName == 0
                return; % Do nothing if no selection
            else
                % Load the required files into the workspace
                load(strcat(folderName, '\data.mat'));
                
                % Plot to Main graph
                plot(app.UIAxes, f, X1);
                
                % Plot to Main table
                app.UITable.ColumnName = {'Center Frequency', 'classificaiton', 'Modulation Type', 'Bandwidth'};
                app.UITable.Data = [centFreq'/(10^6), classification', modulation', band'/(10^6)];
                
                % Initialize Y-axis variables for the remaining plots
                X2 = zeros(size(X1))-100;
                X3 = zeros(size(X1))-100;
                X4 = zeros(size(X1))-100;
                
                plot(app.UIAxes_2, 0, 0);
                plot(app.UIAxes_3, 0, 0);
                plot(app.UIAxes_4, 0, 0);
                app.UITable_2.Data = [];
                app.UITable_3.Data = [];
                app.UITable_4.Data = [];
                
                for i = 1:length(classification)
                
                    if classification(i) == "Wi-Fi"
                        % Plot only Wi-Fi signal portions to the Wi-Fi plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X2(index) = X1(index); % Add Wi-Fi portion to initialized Y-axis variable
                        plot(app.UIAxes_2, f, X2);
                        
                        % Plot only Wi-Fi metadata portions to the Wi-Fi
                        % table
                        app.UITable_2.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_2,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_2,'Data',newData);
                        
                        
                    elseif classification(i) == "Bluetooth"
                        % Plot only Bluetooth signal portions to the Bluetooth plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X3(index) = X1(index); % Add Bluetooth portion to initialized Y-axis variable
                        plot(app.UIAxes_3, f, X3);
                        
                        % Plot only Bluetooth metadata portions to the
                        % Bluetooth table
                        app.UITable_3.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_3,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_3,'Data',newData)
                        
                        
                    else
                        % Plot only remaining signal portions to the Other plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X4(index) = X1(index); % Add Other portion to initialized Y-axis variable
                        plot(app.UIAxes_4, f, X4);
                        
                        % Plot only remaining metadata portions to the
                        % Other table
                        app.UITable_4.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_4,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_4,'Data',newData)
                    end
                end
                              
                runFlag = fopen('Runtime\ran.dyn', 'w');
                fclose(runFlag);
            end
            
            % R Shortcut
            elseif strcmp(key, 'r')
                % Run startup code to gain access to filepaths and necessary
            % variables
            run Scripting\startup.m
            
            % Set the sample flag so that GNURadio will release the sample
            % file
            sampleFlag = fopen('Runtime\sample.dyn', 'w');
            fclose(sampleFlag);
            
            % Import the sample file to MATLAB
            run Scripting\importSample.m
            
            % Call functions and scripts to calculate signal metadata
            run Scripting\freqBand.m
            classification = radioClassify(centFreq, band);
            modulation = modClassify(centFreq, band);
            
            
            % Save the calculated data to a temprary file
            save('Runtime\data.mat', 'f', 'X1', 'centFreq','classification', 'band', 'modulation')
            
            % Plot to Main graph
            plot(app.UIAxes, f, X1);
            
             % Plot to Main Table
            app.UITable.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
            app.UITable.Data = [centFreq'/(10^6), classification', modulation', band'/(10^6)];
            
            % Initialize Y-axis variables for the remaining plots
            X2 = zeros(size(X1))-100;
            X3 = zeros(size(X1))-100;
            X4 = zeros(size(X1))-100;
            
            plot(app.UIAxes_2, 0, 0);
            plot(app.UIAxes_3, 0, 0);
            plot(app.UIAxes_4, 0, 0);
            
            app.UITable_2.Data = [];
            app.UITable_3.Data = [];
            app.UITable_4.Data = [];
            
            % Loop through each of the captured signals
            for i = 1:length(classification)
                
                    if classification(i) == "Wi-Fi"
                        % Plot only Wi-Fi signal portions to the Wi-Fi plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X2(index) = X1(index); % Add Wi-Fi portion to initialized Y-axis variable
                        plot(app.UIAxes_2, f, X2);
                        
                        % Plot only Wi-Fi metadata portions to the Wi-Fi
                        % table
                        app.UITable_2.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_2,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_2,'Data',newData);
                        
                        
                    elseif classification(i) == "Bluetooth"
                        % Plot only Bluetooth signal portions to the Bluetooth plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X3(index) = X1(index); % Add Bluetooth portion to initialized Y-axis variable
                        plot(app.UIAxes_3, f, X3);
                        
                        % Plot only Bluetooth metadata portions to the
                        % Bluetooth table
                        app.UITable_3.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_3,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_3,'Data',newData)
                        
                        
                    else
                        % Plot only remaining signal portions to the Other plot
                        index = (f > (centFreq(i)-0.5*band(i)) & f < (centFreq(i)+0.5*band(i)));
                        X4(index) = X1(index); % Add Other portion to initialized Y-axis variable
                        plot(app.UIAxes_4, f, X4);
                        
                        % Plot only remaining metadata portions to the
                        % Other table
                        app.UITable_4.ColumnName = {'Center Frequency (MHz)', 'classificaiton', 'Modulation Type', 'Bandwidth (MHz)'};
                        oldData = get(app.UITable_4,'Data');
                        newData = [oldData; centFreq(i)/(10^6), classification(i), modulation(i), band(i)/(10^6)];
                        set(app.UITable_4,'Data',newData)
                    end
            end
            
            % Set run flag so that the program knows it has been run
            runFlag = fopen('Runtime\ran.dyn', 'w');
            fclose(runFlag);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.UIAxes = uiaxes(app.SummaryTab);
            title(app.UIAxes, '2.4 GHz Frequency Band - Complete')
            xlabel(app.UIAxes, 'Frequency')
            app.UITable = uitable(app.SummaryTab);
            app.UITable.ColumnName = {'Center Frequency'; 'Classification'; 'Modulation Type'; 'Bandwidth'};
            app.UITable.RowName = {};
            app.WiFiTab = uitab(app.TabGroup);
            app.WiFiTab.Title = 'Wi-Fi';
            app.UIAxes_2 = uiaxes(app.WiFiTab);
            title(app.UIAxes_2, '2.4 GHz Frequency Band - Wi-Fi')
            xlabel(app.UIAxes_2, 'Frequency')
            app.UITable_2 = uitable(app.WiFiTab);
            app.UITable_2.ColumnName = {'Frequency'; 'Modulation Type'; 'Bandwidth'};
            app.UITable_2.RowName = {};
            app.BluetoothTab = uitab(app.TabGroup);
            app.BluetoothTab.Title = 'Bluetooth';
            app.UIAxes_3 = uiaxes(app.BluetoothTab);
            title(app.UIAxes_3, '2.4 GHz Frequency Band - Bluetooth')
            xlabel(app.UIAxes_3, 'Frequency')
            app.UITable_3 = uitable(app.BluetoothTab);
            app.UITable_3.ColumnName = {'Frequency'; 'Modulation Type'; 'Bandwidth'};
            app.UITable_3.RowName = {};
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.UIAxes_4 = uiaxes(app.OtherTab);
            title(app.UIAxes_4, '2.4 GHz Frequency Band - Other')
            xlabel(app.UIAxes_4, 'Frequency')
            app.UITable_4 = uitable(app.OtherTab);
            app.UITable_4.ColumnName = {'Frequency'; 'Modulation Type'; 'Bandwidth'};
            app.UITable_4.RowName = {};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Classification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end